classdef Digi4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PlikMenu                        matlab.ui.container.Menu
        OtwrzMenu                       matlab.ui.container.Menu
        ZapiszobrazMenu                 matlab.ui.container.Menu
        OryginalnamapaMenu              matlab.ui.container.Menu
        MapazROIMenu                    matlab.ui.container.Menu
        CharakterystykifiltrucyfrowegoMenu  matlab.ui.container.Menu
        ZapiszwynikiMenu                matlab.ui.container.Menu
        PojedynczypomiarMenu            matlab.ui.container.Menu
        WybranekrzyweROIMenu            matlab.ui.container.Menu
        WszystkiekrzyweMenu             matlab.ui.container.Menu
        ZapiszparametryfiltrucyfrowegoMenu  matlab.ui.container.Menu
        ZapiszparametryAIMenu           matlab.ui.container.Menu
        ZapiszLOGMenu                   matlab.ui.container.Menu
        EdycjaMenu                      matlab.ui.container.Menu
        ObrmapMenu                      matlab.ui.container.Menu
        wprawoMenu                      matlab.ui.container.Menu
        wlewoMenu                       matlab.ui.container.Menu
        SkalujmapMenu                   matlab.ui.container.Menu
        KolorymapyMenu                  matlab.ui.container.Menu
        ZotyMenu                        matlab.ui.container.Menu
        DodajskaldoobrazuMenu           matlab.ui.container.Menu
        UsuskalzobrazuMenu              matlab.ui.container.Menu
        OpcjeskaliMenu                  matlab.ui.container.Menu
        ROIMenu                         matlab.ui.container.Menu
        DodajkrzywdoROIMenu             matlab.ui.container.Menu
        UsukrzywzROIMenu                matlab.ui.container.Menu
        ZaznaczROIodrcznieMenu          matlab.ui.container.Menu
        UstalparametrydetektoraprogowegoMenu  matlab.ui.container.Menu
        WyznaczROIautomatycznieMenu     matlab.ui.container.Menu
        DetektorprogowyMenu             matlab.ui.container.Menu
        DetektorprogowysegmentacjaMenu  matlab.ui.container.Menu
        WidocznoROIMenu                 matlab.ui.container.Menu
        PokaROIMenu                     matlab.ui.container.Menu
        WyczROIMenu                     matlab.ui.container.Menu
        StylnumerowaniakrzywychwmapieMenu  matlab.ui.container.Menu
        JPKSPMDataProcessingMenu        matlab.ui.container.Menu
        FilltracjaMenu                  matlab.ui.container.Menu
        WyrwnajsygnaMenu                matlab.ui.container.Menu
        PojedynczakrzywaMenu            matlab.ui.container.Menu
        WybranekrzyweROIMenu_2          matlab.ui.container.Menu
        WszystkiekrzyweMenu_2           matlab.ui.container.Menu
        ProjektantfiltruMenu            matlab.ui.container.Menu
        ProjektujfiltrautomatycznieMenu  matlab.ui.container.Menu
        FiltrujMenu                     matlab.ui.container.Menu
        CofnijfiltracjMenu              matlab.ui.container.Menu
        AIMenu                          matlab.ui.container.Menu
        ProjektantsiecineuronowychMenu  matlab.ui.container.Menu
        KlasyfikujkrzyweMenu            matlab.ui.container.Menu
        KlasyfikujgotowymmodelemMenu    matlab.ui.container.Menu
        UczenieznadzoremMenu            matlab.ui.container.Menu
        UczeniebeznadzoruMenu           matlab.ui.container.Menu
        ZmieklasyfikacjkrzywejMenu      matlab.ui.container.Menu
        ZmienaadhezyjnMenu              matlab.ui.container.Menu
        ZmienanieadhezyjnMenu           matlab.ui.container.Menu
        AnalizaMenu                     matlab.ui.container.Menu
        WyznaczmaksymalnadhezjMenu      matlab.ui.container.Menu
        PojedynczypomiarMenu_2          matlab.ui.container.Menu
        WybranekrzyweROIMenu_3          matlab.ui.container.Menu
        WszystkiekrzyweMenu_3           matlab.ui.container.Menu
        ZaznaczpoprawmaksymalnadhezjMenu  matlab.ui.container.Menu
        WyznaczpraczerwaniawizaniaMenu  matlab.ui.container.Menu
        PojedynczypomiarMenu_3          matlab.ui.container.Menu
        WybranekrzyweROIMenu_4          matlab.ui.container.Menu
        WszystkiekrzyweMenu_4           matlab.ui.container.Menu
        ZaznaczpoprawpraczerwaniawizaniaMenu  matlab.ui.container.Menu
        DopasujmodelautomatycznieMenu   matlab.ui.container.Menu
        ModelHertzaSneddonaMenu         matlab.ui.container.Menu
        PojedynczypomiarMenu_4          matlab.ui.container.Menu
        WybranekrzyweROIMenu_5          matlab.ui.container.Menu
        WszystkiekrzyweMenu_5           matlab.ui.container.Menu
        StatystykaMenu                  matlab.ui.container.Menu
        WybierziobliczparametrystatystyczneMenu  matlab.ui.container.Menu
        ObliczwszystkieparametrystatystyczneMenu  matlab.ui.container.Menu
        KreatorhistogramuMenu           matlab.ui.container.Menu
        RysujhistogramautomatycznieMenu  matlab.ui.container.Menu
        WybranekrzyweROIMenu_6          matlab.ui.container.Menu
        WszystkiekrzyweMenu_6           matlab.ui.container.Menu
        DopasujrozkadMenu               matlab.ui.container.Menu
        WybranekrzyweROIMenu_7          matlab.ui.container.Menu
        WszystkiekrzyweMenu_7           matlab.ui.container.Menu
        TestnormalnociMenu              matlab.ui.container.Menu
        TestWShapiroWilkaMenu           matlab.ui.container.Menu
        AutomatyzujMenu                 matlab.ui.container.Menu
        ZaplanujczynnociMenu            matlab.ui.container.Menu
        WykonajprogramMenu              matlab.ui.container.Menu
        OpcjeMenu                       matlab.ui.container.Menu
        PakietjzykowyMenu               matlab.ui.container.Menu
        PolskiPLMenu                    matlab.ui.container.Menu
        AngielskiENGMenu                matlab.ui.container.Menu
        PomocMenu                       matlab.ui.container.Menu
        InformacjeowersjiMenu           matlab.ui.container.Menu
        InstrukcjaMenu                  matlab.ui.container.Menu
        Panel                           matlab.ui.container.Panel
        TabGroup7                       matlab.ui.container.TabGroup
        MapaoryginalnaTab               matlab.ui.container.Tab
        UIAxes_2                        matlab.ui.control.UIAxes
        MAXAdhezjaTab                   matlab.ui.container.Tab
        UIAxes                          matlab.ui.control.UIAxes
        ElastycznoETab                  matlab.ui.container.Tab
        POMIARYPARAMETRYLabel           matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        MapaTab                         matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        ROITab                          matlab.ui.container.Tab
        UITable_2                       matlab.ui.control.Table
        OKNOMAPYLabel                   matlab.ui.control.Label
        DaneMapaLabel                   matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        AnalizadanychTab                matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        LogTextArea                     matlab.ui.control.TextArea
        LOGLabel                        matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        KanaXDropDownLabel_4            matlab.ui.control.Label
        KanalYDropdown                  matlab.ui.control.DropDown
        KanaXDropDownLabel_3            matlab.ui.control.Label
        KanalXDropdown                  matlab.ui.control.DropDown
        EditField_2                     matlab.ui.control.EditField
        EditField                       matlab.ui.control.EditField
        NachyleniepNmLabel_14           matlab.ui.control.Label
        NachyleniepNmLabel_2            matlab.ui.control.Label
        OKNOANALIZYPOMIARWLabel         matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        WynikiTab                       matlab.ui.container.Tab
        TabGroup_2                      matlab.ui.container.TabGroup
        MapaTab_2                       matlab.ui.container.Tab
        TabGroup_5                      matlab.ui.container.TabGroup
        MaksymalnaadhezjaTab_3          matlab.ui.container.Tab
        TabGroup3_7                     matlab.ui.container.TabGroup
        HistogramTab_7                  matlab.ui.container.Tab
        UIAxes2_25                      matlab.ui.control.UIAxes
        RozrzutadhezjaelastycznoTab_3   matlab.ui.container.Tab
        UIAxes2_26                      matlab.ui.control.UIAxes
        RozrzutadhezjapracaTab_3        matlab.ui.container.Tab
        UIAxes2_27                      matlab.ui.control.UIAxes
        Switch_7                        matlab.ui.control.Switch
        NachyleniepNmLabel_11           matlab.ui.control.Label
        PracazerwaniawizaTab_3          matlab.ui.container.Tab
        TabGroup3_8                     matlab.ui.container.TabGroup
        HistogramTab_8                  matlab.ui.container.Tab
        UIAxes2_28                      matlab.ui.control.UIAxes
        RozrzutpracaelastycznoTab_3     matlab.ui.container.Tab
        UIAxes2_29                      matlab.ui.control.UIAxes
        RozrzutpracaadhezjaTab_6        matlab.ui.container.Tab
        UIAxes2_30                      matlab.ui.control.UIAxes
        Switch_8                        matlab.ui.control.Switch
        NachyleniepNmLabel_12           matlab.ui.control.Label
        ModuelastycznociETab_3          matlab.ui.container.Tab
        TabGroup3_9                     matlab.ui.container.TabGroup
        HistogramTab_9                  matlab.ui.container.Tab
        UIAxes2_31                      matlab.ui.control.UIAxes
        RozrzutelastycznoadhezjaTab_3   matlab.ui.container.Tab
        UIAxes2_32                      matlab.ui.control.UIAxes
        RozrzutelastycznopracaTab_3     matlab.ui.container.Tab
        UIAxes2_33                      matlab.ui.control.UIAxes
        Switch_9                        matlab.ui.control.Switch
        NachyleniepNmLabel_13           matlab.ui.control.Label
        ROITab_2                        matlab.ui.container.Tab
        TabGroup_4                      matlab.ui.container.TabGroup
        MaksymalnaadhezjaTab_2          matlab.ui.container.Tab
        TabGroup3_4                     matlab.ui.container.TabGroup
        HistogramTab_4                  matlab.ui.container.Tab
        UIAxes2_16                      matlab.ui.control.UIAxes
        RozrzutadhezjaelastycznoTab_2   matlab.ui.container.Tab
        UIAxes2_17                      matlab.ui.control.UIAxes
        RozrzutadhezjapracaTab_2        matlab.ui.container.Tab
        UIAxes2_18                      matlab.ui.control.UIAxes
        Switch_4                        matlab.ui.control.Switch
        NachyleniepNmLabel_8            matlab.ui.control.Label
        PracazerwaniawizaTab_2          matlab.ui.container.Tab
        TabGroup3_5                     matlab.ui.container.TabGroup
        HistogramTab_5                  matlab.ui.container.Tab
        UIAxes2_19                      matlab.ui.control.UIAxes
        RozrzutpracaelastycznoTab_2     matlab.ui.container.Tab
        UIAxes2_20                      matlab.ui.control.UIAxes
        RozrzutpracaadhezjaTab_5        matlab.ui.container.Tab
        UIAxes2_21                      matlab.ui.control.UIAxes
        Switch_5                        matlab.ui.control.Switch
        NachyleniepNmLabel_9            matlab.ui.control.Label
        ModuelastycznociETab_2          matlab.ui.container.Tab
        TabGroup3_6                     matlab.ui.container.TabGroup
        HistogramTab_6                  matlab.ui.container.Tab
        UIAxes2_22                      matlab.ui.control.UIAxes
        RozrzutelastycznoadhezjaTab_2   matlab.ui.container.Tab
        UIAxes2_23                      matlab.ui.control.UIAxes
        RozrzutelastycznopracaTab_2     matlab.ui.container.Tab
        UIAxes2_24                      matlab.ui.control.UIAxes
        Switch_6                        matlab.ui.control.Switch
        NachyleniepNmLabel_10           matlab.ui.control.Label
    end

    
    properties (Access = private)
        
        curr_folder = ""; %path to current directory
        source_dir = ""; %path to source directory

        lg_set = 1; %1 - polski pakiet językowy, 0 - english language pack
        log = ">>"; %log message string
        header_path = ""; %path to header file string
        raw_data = {}; %cell containing raw data for all force-distance curves
        max_min_Y = []; %table of maximum and minimum Y value
        max_min_X = []; %table of maximum and minimum X value
        W = []; %table of work needed for bond breakage [zJ]
        E = []; %table of modified Young moduli [pN]
        slope_tab = []; %table of slopes [pN/um]
        txt_filenames = []; %struct containing filenames with force-distance curves (name, folder, date, bytes, isdir, datenum)

        spring_sens = 0; %spring sensitivity of cantilever [N/m]
        defl_sens = 0; %deflection sensitivity [nm/V]
        
        channelNames13 = []; %channel names for segments 1 and 3 of measurement (extend, retract)
        channelNames2 = []; %channel names for segment 2 of measurement (pause extend)
        units13 = []; %units for segments 1 and 3 of measurement (extend, retract)
        units2 = []; %units for segment 2 of measurement (pause extend)
        
        I = []; %image file
        sel_kolor = [0, 255, 0]; %colour of a selected force-distance curve - green
        rgbsel = []; %RGB mask of selected pixel
        indXY_sel = []; %indices of selected force-distance curve 
        fd_num = 1; %number of selected force-distance curve
        indXY = []; %indices in matrix of force-distance curves - whole map
        
        h = []; %brush tool
        h_color = [255, 255, 146]; %colour of brush image - yellow
        kolor = [255, 255, 146]; %ROI selection colour - yellow
        krzywe = []; %list of selected in ROI force-distance curve numbers
        rgbI = []; %RGB mask of selected ROI
        num_map = []; %map of curve numbers, accordingly to measurement system
        indXY_ROI = []; %indices in matrix of selected force-distance curves - ROI
        
%         szer_bin = [0, 0, 0]; %image scale (read image option) - [columns (Y), rows (X), channels (Z)]
        szer_bin_check = 0; %scaling coef flag
        file_check = 0; %file selection flag
        ROIreset_flag = 0; %flag of ROI display selection: 1 - on, 0 - off

        dateMeasurement = []; %date and time
        modeName = ""; %measurement mode
        extendTime = 0; %extend scan time
        extendPauseTime = 0; %extend pause time
        retractTime = 0; %retract scan time
        retractPauseTime = 0; %retract pause time
        res_x = 0; %resolution X
        res_y = 0; %resolution Y
        dim_x = 0; %real length X
        dim_y = 0; %real length Y
        z_l = 0; %Z length
        Env = ""; %environment of measurement
        Scanner = ""; %environment of measurement
    end
    
    methods (Access = private)
        
        function updateLog(app, command_num)
            komendy = [">>Nie znaleziono pliku nagłówka.", ">>Header file not found."; %1
                ">>Znaleziono plik nagłówka.", ">>Header file found."; %2
                ">>Wczytano parametry mapy.", ">>Force map parameters read."; %3
                ">>Znaleziono pliki tekstowe.", ">>Text files found."; %4
                ">>Wczytano parametry pomiaru.", ">>Measurement parameters read."; %5
                ">>Wczytano dane numeryczne.", ">>Numeric data read."; %6
                ">>Błąd odczytu danych.", ">>Data read error."; %7
                ">>Wczytano plik obrazu.", ">>Image data read."; %8
                ">>Błąd odczytu obrazu.", ">>Image read error.";%9
                ">>Przeskalowano obraz.", "Image data scaled."; %10
                ">>Błąd skalowania obrazu.", ">>Image scaling error."; %11
                ">>Proszę czekać...", ">>Please wait..."; %12
                ">>Pokazano ROI.", ">>ROI has been shown."; %13
                ">>Ukryto ROI.", ">>ROI has been hidden."; %14
                ">>Utworzono ROI.", ">>ROI has been selected."; %15
                ">>Błąd podczas wyznaczenia ROI.", ">>Error during ROI selection."; %16
                ">>Błąd wyświetlania ROI.", ">>Error during ROI display."; %17
                ">>Wczytano dane opisowe krzywej.", ">>Force-distance curve description read."; %18
                ">>Błąd odczytu danych opisowych krzywej.", ">>Error of force-distance curve description read."]; %19
            
            app.log = [app.log; komendy(command_num, app.lg_set)];
            app.LogTextArea.Value = strcat(app.log(:));  
            %app.LogTextArea.Value = [{app.log}; {komendy(command_num, app.lg_set)}];
        end

        function readHeader(app)

            read_h = fopen(char(app.header_path), 'r');
            scanned_files = textscan(read_h, '%s', 'delimiter', '\n');
        
            dateMeasurement_temp = searchFile(app, scanned_files, "start-time", "=", 1); %date and time
            modeName_temp = searchFile(app, scanned_files, "feedback-mode.name", "=", 1); %measurement mode
            app.extendTime = searchFile(app, scanned_files, "extend-scan-time", "=", 1); %extend scan time
            app.extendPauseTime = searchFile(app, scanned_files, "extended-pause-time", "=", 1); %extend pause time
            app.retractTime = searchFile(app, scanned_files, "retract-scan-time", "=", 1); %retract scan time
            app.retractPauseTime = searchFile(app, scanned_files, "retracted-pause-time", "=", 1); %retract pause time
            app.res_x = searchFile(app, scanned_files, "ilength", "=", 1); %resolution X
            app.res_y = searchFile(app, scanned_files, "jlength", "=", 1); %resolution Y
            app.dim_x = searchFile(app, scanned_files, "ulength", "=", 1); %real length X
            app.dim_y = searchFile(app, scanned_files, "vlength", "=", 1); %real length Y
            app.z_l = searchFile(app, scanned_files, "relative-z-start", "=", 1); %Z length
            app.Env = searchFile(app, scanned_files, "liquid", "=", 1); %environment of measurement
            app.Scanner = searchFile(app, scanned_files, "tip-scanner.xy-scanner.fancy-name", "=", 1); %environment of measurement
        
            %Processing
            app.dateMeasurement = strsplit(dateMeasurement_temp, " "); %date and time
            app.dateMeasurement{2} = erase(app.dateMeasurement{2}, "\");
        
            if app.lg_set && strcmp(modeName_temp, "contact") %mode
                app.modeName = "mod kontaktowy";
            else
                app.modeName = "contact mode";
            end
        
            if app.lg_set && strcmp(app.Env, "true") %environment
                app.Env = "ciecz";
            else
                app.Env = "powietrze";
            end    
        end

        function [mFinal] = searchFile(app, cell_srch, str_srch, split_str, num_segm)

            mIndex = ~cellfun('isempty', strfind(cell_srch{1}, str_srch));
            segmIndex = find(mIndex); %find data for all segments
            mLine = strsplit(cell_srch{1}{segmIndex(num_segm)}, split_str);
            mFinal = mLine{end};
            
        end

        function [channelNames13, channelNames2, units13, units2] = readTxt1(app, a)

            txtfileID = fopen(a(1).name, 'r');
            scanned_txt = textscan(txtfileID, '%s', 'delimiter', '\n');
        
            channelNames_temp13 = searchFile(app, scanned_txt, "fancyNames:", ": ", 1); %channels for segments 1 and 3
            units_temp13 = searchFile(app, scanned_txt, "units:", ": ", 1); %units for segments 1 and 3
            channelNames_temp2 = searchFile(app, scanned_txt, "fancyNames:", ": ", 2); %channels for segment 2
            units_temp2 = searchFile(app, scanned_txt, "units:", ": ", 2); %units for segment 2
            
            %Processing
            channelNames13 = strsplit(channelNames_temp13, '" "'); %channels for segments 1 and 3
            channelNames13 = erase(channelNames13, '"');
            channelNames2 = strsplit(channelNames_temp2, '" "'); %channels for segment 2
            channelNames2 = erase(channelNames2, '"');
            
            units13 = strsplit(units_temp13, ' '); %units for segments 1 and 3
            units2 = strsplit(units_temp2, ' '); %units for segment 2
        
        end

        function [z_main] = readTxt(app, a, iterator, channelNames13, channelNames2)

            txtfileID = fopen(a(iterator).name, 'r'); %read file
            scanned_txt = textscan(txtfileID, '%s', 'delimiter', '\n');
            
            mIndex_b = ~cellfun('isempty', strfind(scanned_txt{1}, "units:")); %find beginning of data cells for all segments
            segmIndex_b = find(mIndex_b) + 2; 
            mIndex_e = ~cellfun('isempty', strfind(scanned_txt{1}, "index:")); %find ending of data cells for all segments
            segmIndex_e = find(mIndex_e) - 2;     
            segmIndex_e(1) = [];
            segmIndex_e = [segmIndex_e; numel(scanned_txt{1})];
            
            z_main = [{} {} {}];
            size_reshape = [numel(channelNames13), numel(channelNames2), numel(channelNames13)];
            for j = 1 : 3
                z_temp = scanned_txt{1}(segmIndex_b(j):segmIndex_e(j));
                z = zeros(size(numel(z_temp), size_reshape(j)));
                for jj = 1 : numel(z_temp) 
                    z2_temp = strsplit(z_temp{jj}, ' ');
                    for jjj = 1 : size_reshape(j)
                        z(jj,jjj) = str2double(z2_temp{jjj});
                    end
                end
                z_main{j} = z;
            end
        end

        function I = readImage(app)

            try
                if app.lg_set
                    [plik, sciezka] = uigetfile({'*.bmp';'*.png';'*.jpg';'*.tiff*'}, ...
                'Wybierz dane obrazowe pomiaru AFM.');
                else
                    [plik, sciezka] = uigetfile({'*.bmp';'*.png';'*.jpg';'*.tiff*'}, ...
                'Select image AFM data.');
                end
    
                if ~isequal(plik, 0)
                    
                    delete(app.h);
                    
                    I = imread(fullfile(sciezka, plik)); %image read 
                    [I, flag] = resizeImage(app, I, plik, sciezka);
                end

                if ~flag
                    updateLog(app, 8);
                else
                    updateLog(app, 9);
                end
            catch
                updateLog(app, 9);
            end

        end

        function [I, flag] = resizeImage(app, I, plik, sciezka)

            try
                delete(app.h);        
    
                I = imread(fullfile(sciezka, plik)); %read image file                                  
                I = imresize( I, 1 / ( (size(I,1) / str2double(app.res_x)) )); %scaling accordnigly to theresolution in rows coefficient                  

                updateLog(app, 10);
                flag = 0;
            catch
                updateLog(app, 11);
                flag = 1;
            end

        end
        
        function showImage(app, I, axes_handle)
            
            try
                title(axes_handle, []); %remove title, axis labels, and tick labels
                xlabel(axes_handle, []); 
                ylabel(axes_handle, []); 
                axes_handle.XAxis.TickLabels = {}; 
                axes_handle.YAxis.TickLabels = {}; 
    
                I_2 = imshow(I, 'Parent', axes_handle, ...
                    'XData', [1 axes_handle.Position(3)], ...
                    'YData', [1 axes_handle.Position(4)]); hold(axes_handle, 'on'); %display image and stretch to fill axes
                drawnow();
                
                axes_handle.XLim = [0 I_2.XData(2)]; %set limits of axes
                axes_handle.YLim = [0 I_2.YData(2)];
            catch %multiple choice - do nothing
            end
            
        end
        
        function indices = fillTable(app, table_handle, map_handle, v_length)
            
            if ~isempty(app.I)
                
                indices = findMapIndices(app, map_handle, v_length);
                
                table_handle.Data(:, 1) = num2cell(1 : v_length); %fill first column with numbers
                table_handle.Data(:, 2) = num2cell(indices(:,1)); %fill X indices of force-distance curves
                table_handle.Data(:, 3) = num2cell(indices(:,2)); %fill Y indices of force-distance curves
                %app.UITable.Data(app.Count.Value, 2) = cellstr(convertCharsToStrings(app.UITable.Data(app.TXIDEditField.Value)); %TXID value, from character to string to cell
                drawnow();
                
            end
            
        end
        
        function createNumeratorsJPK(app)
            
            if ~isempty(app.I)
                v = linspace(1, size(app.I,1) * size(app.I,2), size(app.I,1) * size(app.I,2)); %create map of curve numbers, accordingly to JPK enumerators
                c = mat2cell(v, 1, repmat( size(app.I,2), 1, size(app.I,1) )); %size(app.I,1) - rows, size(app.I,2) - columns
                c(2: 2: fix(numel(c)/2) * 2) = mat2cell( fliplr(cell2mat( ...
                c(fix(numel(c)/2) * 2: -2: 2) )), ...
                1,  repmat(size(app.I,2), 1, fix(size(app.I,1)/2)) );
                app.num_map = flip( reshape(cell2mat(c), size(app.I,2), size(app.I,1))' ); %map of force-distance curves numbers, JPK system
            end
            
        end
        
        function indices = findMapIndices(app, map_values, v_length)
            
                v = 1 : v_length;
                for i = v
                    [indices(i,1), indices(i,2)] = ...
                        find(map_values == v(i));
                end
            
        end
        
        function updatePrompt(app, initial_num)
                         
            if initial_num %check if reset or write data in the text label
                a = [{app.source_dir}, {app.dateMeasurement{1}}, {app.dateMeasurement{2}}, ...
                    {app.Env}, {app.modeName}, {app.Scanner}, {num2str(str2double(app.z_l) / ...
                    str2double(app.extendTime) * 10^6)}, {num2str(str2double(app.z_l) * 10^6)}, ...
                    {app.res_x}, {app.res_y}, {num2str(round(str2double(app.dim_x) * 10^6, 2))}, ...
                    {num2str(round(str2double(app.dim_y) * 10^6, 2))}, ...
                    {app.extendTime}, {app.extendPauseTime}, {app.retractTime}, {app.retractPauseTime}];
            else
                a = [{"-"}, {"-"}, {"-"}, {"-"}, {"-"}, {"-"}, {"-"}, {"-"}, {"-"}, {"-"}, {"-"}, ...
                    {"-"}, {"-"}, {"-"}, {"-"}, {"-"}];
            end
            
            if app.lg_set
                new_prompt = [strcat("Nazwa pliku: ", a{1}), ...
                    strcat("Data akwizycji: ", a{2}), ...
                    strcat("Godzina akwizycji: ", a{3}), newline, ...
                    strcat("Środowisko pomiaru: ", a{4}), ...
                    strcat("Mod: ", a{5}), ... 
                    strcat("Typ próbnika: ", a{6}), newline, ...
                    strcat("Szybkość przykładania siły [µm/s]: ", a{7}), ...
                    strcat("Długość Z [µm]: ", a{8}), newline, ...
                    strcat("Wymiary mapy wys. x szer. [px]: ", a{9}, " x ", a{10}), ...
                    strcat("Wymiary mapy wys. x szer. [µm]: ", a{11}, " x ", a{12}), newline, ...
                    strcat("Czas wydłużania [s]: ", a{13}), ...
                    strcat("Przerwa (wydłużanie) [s]: ", a{14}), ...
                    strcat("Czas retrakcji [s]: ", a{15}), ...
                    strcat("Przerwa (retrakcja) [s]: ", a{16})]; %create the updated prompt - Polish language
            else
                new_prompt = [strcat("File name: -", a{1}), newline, ...
                    strcat("Measurement date: ", a{2}), ...
                    strcat("Measurement hour: ", a{3}), newline, ...
                    strcat("Environment: ", a{4}), ...
                    strcat("Mode: ", a{5}), ... 
                    strcat("Cantilever type: ", a{6}), newline, ...
                    strcat("Loading rate [µm/s]: ", a{7}), ...
                    strcat("Length Z [µm]: ", a{8}), newline, ...
                    strcat("Map dimensions height x width [px]: ", a{9}, " x ", a{10}), ...
                    strcat("Map dimensions height x width [µm]: ", a{11}, " x ", a{12}), newline, ...
                    strcat("Extension time [s]: ", a{13}), ...
                    strcat("Break (extension) [s]: ", a{14}), ...
                    strcat("Retraction time [s]: ", a{15}), ...
                    strcat("Break (retraction) [s]: ", a{16})]; %create the updated prompt - English language
                
            end
            
            app.DaneMapaLabel.Text = new_prompt; %update the prompt
            
        end
        
        function col_sel = colourSelection(app, indice_map, colordata_handle, axes_handle)
                     
            if ~isempty(app.I)
                
                rc = app.I(:, :, 1); %adjustment of colour for selection
                gc = app.I(:, :, 2);
                bc = app.I(:, :, 3);
                rc(indice_map) = colordata_handle(1);
                gc(indice_map) = colordata_handle(2);
                bc(indice_map) = colordata_handle(3);
                col_sel = cat(3, rc, gc, bc);
                
%                 axes(axes_handle);
%                 showImage(app, col_sel, axes_handle); hold(axes_handle, 'on');
                
            end
            
        end
        
        function resetImage(app, axes_handle, image_handle)
            
            if ~isempty(image_handle)
                
                cla(axes_handle); %clear content
                axes(axes_handle); %select axes
                hold(axes_handle, 'on');
                
                showImage(app, image_handle, axes_handle); %hide ROI by resetting figure
            
                if ~isempty(app.rgbI) && app.ROIreset_flag
                    rgbI_temp = app.rgbI;
                    rgbI_temp(app.indXY_sel(1), app.indXY_sel(2), 1) = app.sel_kolor(1);
                    rgbI_temp(app.indXY_sel(1), app.indXY_sel(2), 2) = app.sel_kolor(2);
                    rgbI_temp(app.indXY_sel(1), app.indXY_sel(2), 3) = app.sel_kolor(3);
                    showImage(app, rgbI_temp, axes_handle); %display ROI, if chosen
                else
                    showImage(app, app.rgbsel, axes_handle); %display selection
                end
                
                hold(axes_handle, 'off');
            end
            
        end
        
        function resetSelectionMap(app)
            
            selection_map = logical(zeros(size(app.I))); %create binary mask for colouring function, based on indices of selected pixel
            selection_map(app.indXY_sel(1), app.indXY_sel(2)) = 1; %select the pixel to be coloured
            app.rgbsel = colourSelection(app, selection_map, app.sel_kolor, app.UIAxes_2); %display selected pixel in main image
            
        end
        
        function readFDCurve(app)
        
            [app.raw_data{app.fd_num}] = readTxt(app, app.txt_filenames, app.fd_num, ...
                app.channelNames13, app.channelNames2); %read channel data  
            
            app.KanalXDropdown.Items = app.channelNames13; %update channel names and enable dropdownboxes
            app.KanalYDropdown.Items = app.channelNames13;
            set(app.KanalXDropdown.Enable, 'on');
            set(app.KanalYDropdown.Enable, 'on');
            app.KanalXDropdown.Value = app.channelNames(1);
            app.KanalYDropdown.Value = app.channelNames(1);


        end
        
        function readFirstFDCurve(app)
            
            [app.channelNames13, app.channelNames2, app.units13, app.units2] = ...
                readTxt1(app, app.txt_filenames); %read channels and units
            updateLog(app, 5);
            updateLog(app, 12);
            
        end
        
        function updatePrompt2(app, initial_num)
            
            try
               if initial_num %check if reset or write data in the text label
                    a = [{num2str(app.fd_num)}, {num2str(app.max_min_Y(app.fd_num,1))}, ...
                        {num2str(app.max_min_Y(app.fd_num,2))}, {num2str(app.max_min_X(app.fd_num,1))}, ...
                        {num2str(app.max_min_X(app.fd_num,2))}, {num2str(app.W(app.fd_num))}, ...
                        {num2str(app.E(app.fd_num))}, {num2str(app.slope_tab(app.fd_num))}];
                else
                    a = [{"-"}, {"-"}, {"-"}, {"-"}, {"-"}, {"-"}, {"-"}, {"-"}];
                end
                
                if app.lg_set
                    
                    new_prompt = [strcat("Numer krzywej: ", a{1}), ...
                        strcat("Maksimum Y [pN]: ", a{2}), ...
                        strcat("Minimum Y (maksymalna adhezja) [pN]: ", a{3}), ...
                        strcat("Maksimum X [μm]: ", a{4}), ...
                        strcat("Minimum X [μm]: ", a{5}), ...
                        strcat("Praca potrzebna do zerwania wiązań [zJ]: ", a{6}), ...
                        strcat("Moduł elastyczności E [Pa]: ", a{7}), ...
                        strcat("Nachylenie [pN/μm]: ", a{8})]; %create the updated prompt - Polish language
    
                else
                    new_prompt = [strcat("Curve number: ", a{1}), ...
                        strcat("Maximum Y [pN]: ", a{2}), ...
                        strcat("Minimum Y (minimal adhesive force) [pN]: ", a{3}), ...
                        strcat("Maximum X [μm]: ", a{4}), ...
                        strcat("Minimum X [μm]: ", a{5}), ...
                        strcat("Work needed for bond brekaage [zJ]: ", a{6}), ...
                        strcat("Elasticity modulus E [Pa]: ", a{7}), ...
                        strcat("Slope [pN/μm]: ", a{8})]; %create the updated prompt - English language
                    
                end
                
                app.NachyleniepNmLabel_2.Text = new_prompt; %update the prompt
                updateLog(app, 18);
            
            catch
                updateLog(app, 19);
            end
            
        end
    end %methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            updatePrompt(app, 0);
        end

        % Menu selected function: OtwrzMenu
        function PlikOtworz_callback(app, event)
            %% Get and enter directory
            
            source_dir = uigetdir([]); 
            curr_folder = pwd;
            addpath(source_dir);
            addpath(curr_folder);
            cd(source_dir);
            
            app.source_dir = source_dir;
            app.curr_folder = curr_folder;
            
            %% Header file read section
            
            headerfile_loc = dir('*header.properties*');
            if ~isempty(headerfile_loc) %check if file is available
                updateLog(app, 2);
                
                app.header_path = strcat(headerfile_loc.folder, filesep, headerfile_loc.name);
                readHeader(app); %extract parameters
                
                updateLog(app, 3);
            else
                updateLog(app, 1);
            end
                      
            %% Curve files read section
            
            try
                app.txt_filenames = dir(fullfile(source_dir, '*.txt')); %table of txt filenames
                app.raw_data = cell(numel(app.txt_filenames), 1); %allocate memory for cell containing channel data
                updateLog(app, 4);
                
                readFirstFDCurve(app); %read first force-distance curve data, for channel names
                readFDCurve(app); %save first curve data for display

                updateLog(app, 6);
            catch
                updateLog(app, 7);
            end

            %% Read image data

            app.I = readImage(app); %read image data
            
            app.indXY_sel = [size(app.I,1),1]; %reset index of selection
            app.ROIreset_flag = 0; %reset ROI selection flag
            app.indXY_ROI = []; %reset ROI table
            app.UITable_2.Data = {}; %clear ROI uitable
            resetSelectionMap(app); %create RGB map of selection indice
            resetImage(app, app.UIAxes_2, app.I); %reset image display
            
            if ~isempty(app.I)
                
                updatePrompt(app, 1); %update the prompt in window below image of original map              
                createNumeratorsJPK(app); %create map of indices

                %% Fill in the table

                app.UITable.Data = {}; %clear main uitable
                app.indXY = fillTable(app, app.UITable, app.num_map, size(app.I,1) * size(app.I,2)); %fill main table with all force-distance curves
           end
        end

        % Menu selected function: ZaznaczROIodrcznieMenu
        function ZaznaczROI_callback(app, event)
          
            try
                delete(app.h); %reset the brush tool
                
                if app.lg_set
                    nam = "Zaznacz ROI.";
                else
                    nam = "Select ROI.";
                end
                
                ax = uiaxes(); %open window for ROI selection
                %ax.Title.String = nam;
                %showImage(app, app.I, ax);  
                imshow(app.I, 'Parent', ax);
                app.h = imfreehand(ax);
                %close(ax);
                
                %app.h = imfreehand; %brush tool for ROI creation
                binarny = app.h.createMask();
                pos = app.h.getPosition();
            
                axes(app.UIAxes_2);
                %plot(pos(:,1), pos(:,2), '-g', 'Parent', app.UIAxes_2); hold(app.UIAxes_2, 'on'); %display ROI in selection uiaxes
                app.rgbI = colourSelection(app, binarny, app.h_color, app.UIAxes_2); %display ROI in main image
                
                app.krzywe = sort(app.num_map(binarny)); %list of selected force-distance curves
                if ~isempty(app.krzywe)
                    app.UITable_2.Data = {}; %clear ROI uitable
                    app.indXY_ROI = fillTable(app, app.UITable_2, app.num_map, numel(app.krzywe)); %fill ROI table with selected force-distance curves  
                end
                
                app.ROIreset_flag = 1; %turn on ROI selection flag
                resetImage(app, app.UIAxes_2, app.I); %display the ROI
                
                updateLog(app, 15);
            catch
                updateLog(app, 16);
            end
        
        end

        % Menu selected function: PokaROIMenu
        function PokazROI_Callback(app, event)
            
            try
                app.ROIreset_flag = 1;
                resetImage(app, app.UIAxes_2, app.I); %if ROI selected, reset the image with ROI
                
                updateLog(app, 13);
            catch
                updateLog(app, 17);
            end
            
        end

        % Menu selected function: WyczROIMenu
        function SchowajROI_Callback(app, event)
            
            try
                app.ROIreset_flag = 0;
                resetImage(app, app.UIAxes_2, app.I); %reset the image without shown ROI
                
                updateLog(app, 14);
            catch
                updateLog(app, 17);
            end
            
        end

        % Menu selected function: DodajkrzywdoROIMenu
        function DodajDoROI_Callback(app, event)
            
        end

        % Menu selected function: UsukrzywzROIMenu
        function UsunZROI_Callback(app, event)
            
        end

        % Cell selection callback: UITable
        function SelectCell_Callback(app, event)
            
            indices = event.Indices; %fetch the selected cell
            app.fd_num = indices(1);
            app.indXY_sel = [app.indXY(indices(1),1), app.indXY(indices(1),2)]; %find the respective force-distance curve indices in image
            
            resetSelectionMap(app); %create RGB map of selection indice           
            resetImage(app, app.UIAxes_2, app.I); %display the image with new selection
            
            readFDCurve(app); %read currently selected force-distance curve
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PlikMenu = uimenu(app.UIFigure);
            app.PlikMenu.Text = 'Plik';
            app.OtwrzMenu = uimenu(app.PlikMenu);
            app.OtwrzMenu.MenuSelectedFcn = createCallbackFcn(app, @PlikOtworz_callback, true);
            app.OtwrzMenu.Text = 'Otwórz';
            app.ZapiszobrazMenu = uimenu(app.PlikMenu);
            app.ZapiszobrazMenu.Text = 'Zapisz obraz';
            app.OryginalnamapaMenu = uimenu(app.ZapiszobrazMenu);
            app.OryginalnamapaMenu.Text = 'Oryginalna mapa';
            app.MapazROIMenu = uimenu(app.ZapiszobrazMenu);
            app.MapazROIMenu.Text = 'Mapa z ROI';
            app.CharakterystykifiltrucyfrowegoMenu = uimenu(app.ZapiszobrazMenu);
            app.CharakterystykifiltrucyfrowegoMenu.Text = 'Charakterystyki filtru cyfrowego';
            app.ZapiszwynikiMenu = uimenu(app.PlikMenu);
            app.ZapiszwynikiMenu.Text = 'Zapisz wyniki';
            app.PojedynczypomiarMenu = uimenu(app.ZapiszwynikiMenu);
            app.PojedynczypomiarMenu.Text = 'Pojedynczy pomiar';
            app.WybranekrzyweROIMenu = uimenu(app.ZapiszwynikiMenu);
            app.WybranekrzyweROIMenu.Text = 'Wybrane krzywe (ROI)';
            app.WszystkiekrzyweMenu = uimenu(app.ZapiszwynikiMenu);
            app.WszystkiekrzyweMenu.Text = 'Wszystkie krzywe';
            app.ZapiszparametryfiltrucyfrowegoMenu = uimenu(app.ZapiszwynikiMenu);
            app.ZapiszparametryfiltrucyfrowegoMenu.Text = 'Zapisz parametry filtru cyfrowego';
            app.ZapiszparametryAIMenu = uimenu(app.ZapiszwynikiMenu);
            app.ZapiszparametryAIMenu.Text = 'Zapisz parametry AI';
            app.ZapiszLOGMenu = uimenu(app.ZapiszwynikiMenu);
            app.ZapiszLOGMenu.Text = 'Zapisz LOG';
            app.EdycjaMenu = uimenu(app.UIFigure);
            app.EdycjaMenu.Text = 'Edycja';
            app.ObrmapMenu = uimenu(app.EdycjaMenu);
            app.ObrmapMenu.Text = 'Obróć mapę';
            app.wprawoMenu = uimenu(app.ObrmapMenu);
            app.wprawoMenu.Text = '90° w prawo';
            app.wlewoMenu = uimenu(app.ObrmapMenu);
            app.wlewoMenu.Text = '90° w lewo';
            app.SkalujmapMenu = uimenu(app.EdycjaMenu);
            app.SkalujmapMenu.Text = 'Skaluj mapę';
            app.KolorymapyMenu = uimenu(app.EdycjaMenu);
            app.KolorymapyMenu.Text = 'Kolory mapy';
            app.ZotyMenu = uimenu(app.KolorymapyMenu);
            app.ZotyMenu.Text = 'Złoty';
            app.DodajskaldoobrazuMenu = uimenu(app.EdycjaMenu);
            app.DodajskaldoobrazuMenu.Text = 'Dodaj skalę do obrazu';
            app.UsuskalzobrazuMenu = uimenu(app.EdycjaMenu);
            app.UsuskalzobrazuMenu.Text = 'Usuń skalę z obrazu';
            app.OpcjeskaliMenu = uimenu(app.EdycjaMenu);
            app.OpcjeskaliMenu.Text = 'Opcje skali';
            app.ROIMenu = uimenu(app.UIFigure);
            app.ROIMenu.Text = 'ROI';
            app.DodajkrzywdoROIMenu = uimenu(app.ROIMenu);
            app.DodajkrzywdoROIMenu.MenuSelectedFcn = createCallbackFcn(app, @DodajDoROI_Callback, true);
            app.DodajkrzywdoROIMenu.Text = 'Dodaj krzywą do ROI';
            app.UsukrzywzROIMenu = uimenu(app.ROIMenu);
            app.UsukrzywzROIMenu.MenuSelectedFcn = createCallbackFcn(app, @UsunZROI_Callback, true);
            app.UsukrzywzROIMenu.Text = 'Usuń krzywą z ROI';
            app.ZaznaczROIodrcznieMenu = uimenu(app.ROIMenu);
            app.ZaznaczROIodrcznieMenu.MenuSelectedFcn = createCallbackFcn(app, @ZaznaczROI_callback, true);
            app.ZaznaczROIodrcznieMenu.Text = 'Zaznacz ROI odręcznie';
            app.UstalparametrydetektoraprogowegoMenu = uimenu(app.ROIMenu);
            app.UstalparametrydetektoraprogowegoMenu.Text = 'Ustal parametry detektora progowego';
            app.WyznaczROIautomatycznieMenu = uimenu(app.ROIMenu);
            app.WyznaczROIautomatycznieMenu.Text = 'Wyznacz ROI automatycznie';
            app.DetektorprogowyMenu = uimenu(app.WyznaczROIautomatycznieMenu);
            app.DetektorprogowyMenu.Text = 'Detektor progowy';
            app.DetektorprogowysegmentacjaMenu = uimenu(app.WyznaczROIautomatycznieMenu);
            app.DetektorprogowysegmentacjaMenu.Text = 'Detektor progowy + segmentacja';
            app.WidocznoROIMenu = uimenu(app.ROIMenu);
            app.WidocznoROIMenu.Text = 'Widoczność ROI';
            app.PokaROIMenu = uimenu(app.WidocznoROIMenu);
            app.PokaROIMenu.MenuSelectedFcn = createCallbackFcn(app, @PokazROI_Callback, true);
            app.PokaROIMenu.Text = 'Pokaż ROI';
            app.WyczROIMenu = uimenu(app.WidocznoROIMenu);
            app.WyczROIMenu.MenuSelectedFcn = createCallbackFcn(app, @SchowajROI_Callback, true);
            app.WyczROIMenu.Text = 'Wyłącz ROI';
            app.StylnumerowaniakrzywychwmapieMenu = uimenu(app.ROIMenu);
            app.StylnumerowaniakrzywychwmapieMenu.Text = 'Styl numerowania krzywych w mapie';
            app.JPKSPMDataProcessingMenu = uimenu(app.StylnumerowaniakrzywychwmapieMenu);
            app.JPKSPMDataProcessingMenu.Text = 'JPKSPM Data Processing';
            app.FilltracjaMenu = uimenu(app.UIFigure);
            app.FilltracjaMenu.Text = 'Filltracja';
            app.WyrwnajsygnaMenu = uimenu(app.FilltracjaMenu);
            app.WyrwnajsygnaMenu.Text = 'Wyrównaj sygnał';
            app.PojedynczakrzywaMenu = uimenu(app.WyrwnajsygnaMenu);
            app.PojedynczakrzywaMenu.Text = 'Pojedyncza krzywa';
            app.WybranekrzyweROIMenu_2 = uimenu(app.WyrwnajsygnaMenu);
            app.WybranekrzyweROIMenu_2.Text = 'Wybrane krzywe (ROI)';
            app.WszystkiekrzyweMenu_2 = uimenu(app.WyrwnajsygnaMenu);
            app.WszystkiekrzyweMenu_2.Text = 'Wszystkie krzywe';
            app.ProjektantfiltruMenu = uimenu(app.FilltracjaMenu);
            app.ProjektantfiltruMenu.Text = 'Projektant filtru';
            app.ProjektujfiltrautomatycznieMenu = uimenu(app.FilltracjaMenu);
            app.ProjektujfiltrautomatycznieMenu.Text = 'Projektuj filtr automatycznie';
            app.FiltrujMenu = uimenu(app.FilltracjaMenu);
            app.FiltrujMenu.Text = 'Filtruj';
            app.CofnijfiltracjMenu = uimenu(app.FilltracjaMenu);
            app.CofnijfiltracjMenu.Text = 'Cofnij filtrację';
            app.AIMenu = uimenu(app.UIFigure);
            app.AIMenu.Text = 'AI';
            app.ProjektantsiecineuronowychMenu = uimenu(app.AIMenu);
            app.ProjektantsiecineuronowychMenu.Text = 'Projektant sieci neuronowych';
            app.KlasyfikujkrzyweMenu = uimenu(app.AIMenu);
            app.KlasyfikujkrzyweMenu.Text = 'Klasyfikuj krzywe';
            app.KlasyfikujgotowymmodelemMenu = uimenu(app.AIMenu);
            app.KlasyfikujgotowymmodelemMenu.Text = 'Klasyfikuj gotowym modelem';
            app.UczenieznadzoremMenu = uimenu(app.KlasyfikujgotowymmodelemMenu);
            app.UczenieznadzoremMenu.Text = 'Uczenie z nadzorem';
            app.UczeniebeznadzoruMenu = uimenu(app.KlasyfikujgotowymmodelemMenu);
            app.UczeniebeznadzoruMenu.Text = 'Uczenie bez nadzoru';
            app.ZmieklasyfikacjkrzywejMenu = uimenu(app.AIMenu);
            app.ZmieklasyfikacjkrzywejMenu.Text = 'Zmień klasyfikację krzywej';
            app.ZmienaadhezyjnMenu = uimenu(app.ZmieklasyfikacjkrzywejMenu);
            app.ZmienaadhezyjnMenu.Text = 'Zmień na "adhezyjną"';
            app.ZmienanieadhezyjnMenu = uimenu(app.ZmieklasyfikacjkrzywejMenu);
            app.ZmienanieadhezyjnMenu.Text = 'Zmień na "nieadhezyjną"';
            app.AnalizaMenu = uimenu(app.UIFigure);
            app.AnalizaMenu.Text = 'Analiza';
            app.WyznaczmaksymalnadhezjMenu = uimenu(app.AnalizaMenu);
            app.WyznaczmaksymalnadhezjMenu.Text = 'Wyznacz maksymalną adhezję';
            app.PojedynczypomiarMenu_2 = uimenu(app.WyznaczmaksymalnadhezjMenu);
            app.PojedynczypomiarMenu_2.Text = 'Pojedynczy pomiar';
            app.WybranekrzyweROIMenu_3 = uimenu(app.WyznaczmaksymalnadhezjMenu);
            app.WybranekrzyweROIMenu_3.Text = 'Wybrane krzywe (ROI)';
            app.WszystkiekrzyweMenu_3 = uimenu(app.WyznaczmaksymalnadhezjMenu);
            app.WszystkiekrzyweMenu_3.Text = 'Wszystkie krzywe';
            app.ZaznaczpoprawmaksymalnadhezjMenu = uimenu(app.AnalizaMenu);
            app.ZaznaczpoprawmaksymalnadhezjMenu.Text = 'Zaznacz / popraw maksymalną adhezję';
            app.WyznaczpraczerwaniawizaniaMenu = uimenu(app.AnalizaMenu);
            app.WyznaczpraczerwaniawizaniaMenu.Text = 'Wyznacz pracę zerwania wiązania';
            app.PojedynczypomiarMenu_3 = uimenu(app.WyznaczpraczerwaniawizaniaMenu);
            app.PojedynczypomiarMenu_3.Text = 'Pojedynczy pomiar';
            app.WybranekrzyweROIMenu_4 = uimenu(app.WyznaczpraczerwaniawizaniaMenu);
            app.WybranekrzyweROIMenu_4.Text = 'Wybrane krzywe (ROI)';
            app.WszystkiekrzyweMenu_4 = uimenu(app.WyznaczpraczerwaniawizaniaMenu);
            app.WszystkiekrzyweMenu_4.Text = 'Wszystkie krzywe';
            app.ZaznaczpoprawpraczerwaniawizaniaMenu = uimenu(app.AnalizaMenu);
            app.ZaznaczpoprawpraczerwaniawizaniaMenu.Text = 'Zaznacz / popraw pracę zerwania wiązania';
            app.DopasujmodelautomatycznieMenu = uimenu(app.AnalizaMenu);
            app.DopasujmodelautomatycznieMenu.Text = 'Dopasuj model automatycznie';
            app.ModelHertzaSneddonaMenu = uimenu(app.DopasujmodelautomatycznieMenu);
            app.ModelHertzaSneddonaMenu.Text = 'Model Hertza-Sneddona';
            app.PojedynczypomiarMenu_4 = uimenu(app.ModelHertzaSneddonaMenu);
            app.PojedynczypomiarMenu_4.Text = 'Pojedynczy pomiar';
            app.WybranekrzyweROIMenu_5 = uimenu(app.ModelHertzaSneddonaMenu);
            app.WybranekrzyweROIMenu_5.Text = 'Wybrane krzywe (ROI)';
            app.WszystkiekrzyweMenu_5 = uimenu(app.ModelHertzaSneddonaMenu);
            app.WszystkiekrzyweMenu_5.Text = 'Wszystkie krzywe';
            app.StatystykaMenu = uimenu(app.UIFigure);
            app.StatystykaMenu.Text = 'Statystyka';
            app.WybierziobliczparametrystatystyczneMenu = uimenu(app.StatystykaMenu);
            app.WybierziobliczparametrystatystyczneMenu.Text = 'Wybierz i oblicz parametry statystyczne';
            app.ObliczwszystkieparametrystatystyczneMenu = uimenu(app.StatystykaMenu);
            app.ObliczwszystkieparametrystatystyczneMenu.Text = 'Oblicz wszystkie parametry statystyczne';
            app.KreatorhistogramuMenu = uimenu(app.StatystykaMenu);
            app.KreatorhistogramuMenu.Text = 'Kreator histogramu';
            app.RysujhistogramautomatycznieMenu = uimenu(app.StatystykaMenu);
            app.RysujhistogramautomatycznieMenu.Text = 'Rysuj histogram automatycznie';
            app.WybranekrzyweROIMenu_6 = uimenu(app.RysujhistogramautomatycznieMenu);
            app.WybranekrzyweROIMenu_6.Text = 'Wybrane krzywe (ROI)';
            app.WszystkiekrzyweMenu_6 = uimenu(app.RysujhistogramautomatycznieMenu);
            app.WszystkiekrzyweMenu_6.Text = 'Wszystkie krzywe';
            app.DopasujrozkadMenu = uimenu(app.StatystykaMenu);
            app.DopasujrozkadMenu.Text = 'Dopasuj rozkład';
            app.WybranekrzyweROIMenu_7 = uimenu(app.DopasujrozkadMenu);
            app.WybranekrzyweROIMenu_7.Text = 'Wybrane krzywe (ROI)';
            app.WszystkiekrzyweMenu_7 = uimenu(app.DopasujrozkadMenu);
            app.WszystkiekrzyweMenu_7.Text = 'Wszystkie krzywe';
            app.TestnormalnociMenu = uimenu(app.StatystykaMenu);
            app.TestnormalnociMenu.Text = 'Test normalności';
            app.TestWShapiroWilkaMenu = uimenu(app.TestnormalnociMenu);
            app.TestWShapiroWilkaMenu.Text = 'Test W Shapiro-Wilka';
            app.AutomatyzujMenu = uimenu(app.UIFigure);
            app.AutomatyzujMenu.Text = 'Automatyzuj';
            app.ZaplanujczynnociMenu = uimenu(app.AutomatyzujMenu);
            app.ZaplanujczynnociMenu.Text = 'Zaplanuj czynności';
            app.WykonajprogramMenu = uimenu(app.AutomatyzujMenu);
            app.WykonajprogramMenu.Text = 'Wykonaj program';
            app.OpcjeMenu = uimenu(app.UIFigure);
            app.OpcjeMenu.Text = 'Opcje';
            app.PakietjzykowyMenu = uimenu(app.OpcjeMenu);
            app.PakietjzykowyMenu.Text = 'Pakiet językowy';
            app.PolskiPLMenu = uimenu(app.PakietjzykowyMenu);
            app.PolskiPLMenu.Text = 'Polski (PL)';
            app.AngielskiENGMenu = uimenu(app.PakietjzykowyMenu);
            app.AngielskiENGMenu.Text = 'Angielski (ENG)';
            app.PomocMenu = uimenu(app.UIFigure);
            app.PomocMenu.Text = 'Pomoc';
            app.InformacjeowersjiMenu = uimenu(app.PomocMenu);
            app.InformacjeowersjiMenu.Text = 'Informacje o wersji';
            app.InstrukcjaMenu = uimenu(app.PomocMenu);
            app.InstrukcjaMenu.Text = 'Instrukcja';
            app.TabGroup2 = uitabgroup(app.UIFigure);
            app.AnalizadanychTab = uitab(app.TabGroup2);
            app.AnalizadanychTab.Title = 'Analiza danych';
            app.Panel_2 = uipanel(app.AnalizadanychTab);
            app.UIAxes2 = uiaxes(app.Panel_2);
            xlabel(app.UIAxes2, 'Pozycja ostrza [μm]')
            ylabel(app.UIAxes2, 'Siła oddziaływania [pN]')
            app.OKNOANALIZYPOMIARWLabel = uilabel(app.Panel_2);
            app.OKNOANALIZYPOMIARWLabel.Text = 'OKNO ANALIZY POMIARÓW';
            app.NachyleniepNmLabel_2 = uilabel(app.Panel_2);
            app.NachyleniepNmLabel_2.Text = {'Numer krzywej: -'; 'Maksimum Y [pN]: -'; 'Minimum Y (maksymalna adhezja) [pN]: -'; 'Maksimum X [μm]: -'; 'Minimum X [μm]: -'; 'Praca potrzebna do zerwania wiązań [zJ]: -'; 'Moduł elastyczności E [Pa]: -'; 'Nachylenie [pN/μm]: -'; ''};
            app.NachyleniepNmLabel_14 = uilabel(app.Panel_2);
            app.NachyleniepNmLabel_14.Text = {'Stała sprężystości próbnika [N/m]: '; 'Wrażliwość odkształceniowa [nm/V]:'};
            app.EditField = uieditfield(app.Panel_2, 'text');
            app.EditField_2 = uieditfield(app.Panel_2, 'text');
            app.KanalXDropdown = uidropdown(app.Panel_2);
            app.KanalXDropdown.Items = {'Wysokość', 'Maksymalne oddziaływanie adhezyjne', 'Występowanie adhezji'};
            app.KanalXDropdown.Enable = 'off';
            app.KanalXDropdown.Value = 'Wysokość';
            app.KanaXDropDownLabel_3 = uilabel(app.Panel_2);
            app.KanaXDropDownLabel_3.Text = 'Kanał X:';
            app.KanalYDropdown = uidropdown(app.Panel_2);
            app.KanalYDropdown.Items = {'Wysokość', 'Maksymalne oddziaływanie adhezyjne', 'Występowanie adhezji'};
            app.KanalYDropdown.Enable = 'off';
            app.KanalYDropdown.Value = 'Wysokość';
            app.KanaXDropDownLabel_4 = uilabel(app.Panel_2);
            app.KanaXDropDownLabel_4.Text = 'Kanał Y:';
            app.Panel_4 = uipanel(app.AnalizadanychTab);
            app.LOGLabel = uilabel(app.Panel_4);
            app.LOGLabel.Text = 'LOG';
            app.LogTextArea = uitextarea(app.Panel_4);
            app.LogTextArea.Value = {'>>'};
            app.WynikiTab = uitab(app.TabGroup2);
            app.WynikiTab.Title = 'Wyniki';
            app.TabGroup_2 = uitabgroup(app.WynikiTab);
            app.MapaTab_2 = uitab(app.TabGroup_2);
            app.MapaTab_2.Title = 'Mapa';
            app.TabGroup_5 = uitabgroup(app.MapaTab_2);
            app.MaksymalnaadhezjaTab_3 = uitab(app.TabGroup_5);
            app.MaksymalnaadhezjaTab_3.Title = 'Maksymalna adhezja ';
            app.NachyleniepNmLabel_11 = uilabel(app.MaksymalnaadhezjaTab_3);
            app.NachyleniepNmLabel_11.Text = {'Wartość średnia maksymalnej adhezji [pN]: -'; 'Mediana maksymalnej adhezji [pN]: -'; ''; 'Test Shapiro-Wilka normalności rozkładu:'; 'p: -'; 'W: -'; ''; 'Dopasowany rozkład: -'; ''; 'Korelacje (R Pearsona):'; 'Adhezja-elastyczność: -'; 'Adhezja-praca: -'};
            app.Switch_7 = uiswitch(app.MaksymalnaadhezjaTab_3, 'slider');
            app.Switch_7.Items = {'Średnia', 'Mediana'};
            app.Switch_7.Value = 'Średnia';
            app.TabGroup3_7 = uitabgroup(app.MaksymalnaadhezjaTab_3);
            app.HistogramTab_7 = uitab(app.TabGroup3_7);
            app.HistogramTab_7.Title = 'Histogram';
            app.UIAxes2_25 = uiaxes(app.HistogramTab_7);
            title(app.UIAxes2_25, 'Histogram maksymalnego oddziaływania adhezyjnego - wszystkie pomiary')
            xlabel(app.UIAxes2_25, 'Liczba zliczeń (szer. binu: - pN)')
            ylabel(app.UIAxes2_25, 'Maksymalne oddziaływanie adhezyjne [pN]')
            app.RozrzutadhezjaelastycznoTab_3 = uitab(app.TabGroup3_7);
            app.RozrzutadhezjaelastycznoTab_3.Title = 'Rozrzut adhezja-elastyczność';
            app.UIAxes2_26 = uiaxes(app.RozrzutadhezjaelastycznoTab_3);
            title(app.UIAxes2_26, 'Rozrzut adhezja-elastyczność - wszystkie pomiary')
            xlabel(app.UIAxes2_26, 'Maksymalne oddziaływanie adhezyjne [pN]')
            ylabel(app.UIAxes2_26, 'Moduł elastyczności E [Pa]')
            app.RozrzutadhezjapracaTab_3 = uitab(app.TabGroup3_7);
            app.RozrzutadhezjapracaTab_3.Title = 'Rozrzut adhezja-praca';
            app.UIAxes2_27 = uiaxes(app.RozrzutadhezjapracaTab_3);
            title(app.UIAxes2_27, 'Rozrzut adhezja-praca - wszystkie pomiary')
            xlabel(app.UIAxes2_27, 'Maksymalne oddziaływanie adhezyjne [pN]')
            ylabel(app.UIAxes2_27, 'Praca zerwania wiązań [zJ]')
            app.PracazerwaniawizaTab_3 = uitab(app.TabGroup_5);
            app.PracazerwaniawizaTab_3.Title = 'Praca zerwania wiązań';
            app.NachyleniepNmLabel_12 = uilabel(app.PracazerwaniawizaTab_3);
            app.NachyleniepNmLabel_12.Text = {'Wartość średnia pracy zerwania wiązań [zJ]: -'; 'Mediana pracy zerwania wiązań [zJ]: -'; ''; 'Test Shapiro-Wilka normalności rozkładu:'; 'p: -'; 'W: -'; ''; 'Dopasowany rozkład: -'; ''; 'Korelacje (R Pearsona):'; 'Praca-elastyczność: -'; 'Praca-adhezja: -'};
            app.Switch_8 = uiswitch(app.PracazerwaniawizaTab_3, 'slider');
            app.Switch_8.Items = {'Średnia', 'Mediana'};
            app.Switch_8.Value = 'Średnia';
            app.TabGroup3_8 = uitabgroup(app.PracazerwaniawizaTab_3);
            app.HistogramTab_8 = uitab(app.TabGroup3_8);
            app.HistogramTab_8.Title = 'Histogram';
            app.UIAxes2_28 = uiaxes(app.HistogramTab_8);
            title(app.UIAxes2_28, 'Histogram pracy zerwania wiązań - wszystkie pomiary')
            xlabel(app.UIAxes2_28, 'Liczba zliczeń (szer. binu: - zJ)')
            ylabel(app.UIAxes2_28, 'Praca zerwania wiązań [zJ]')
            app.RozrzutpracaelastycznoTab_3 = uitab(app.TabGroup3_8);
            app.RozrzutpracaelastycznoTab_3.Title = 'Rozrzut praca-elastyczność';
            app.UIAxes2_29 = uiaxes(app.RozrzutpracaelastycznoTab_3);
            title(app.UIAxes2_29, 'Rozrzut praca-elastyczność - wszystkie pomiary')
            xlabel(app.UIAxes2_29, 'Praca zerwania wiązań [zJ]')
            ylabel(app.UIAxes2_29, 'Moduł elastyczności E [Pa]')
            app.RozrzutpracaadhezjaTab_6 = uitab(app.TabGroup3_8);
            app.RozrzutpracaadhezjaTab_6.Title = 'Rozrzut praca-adhezja';
            app.UIAxes2_30 = uiaxes(app.RozrzutpracaadhezjaTab_6);
            title(app.UIAxes2_30, 'Rozrzut praca-adhezja - wszystkie pomiary')
            xlabel(app.UIAxes2_30, 'Praca zerwania wiązań [zJ]')
            ylabel(app.UIAxes2_30, 'Maksymalne oddziaływanie adhezyjne [pN]')
            app.ModuelastycznociETab_3 = uitab(app.TabGroup_5);
            app.ModuelastycznociETab_3.Title = 'Moduł elastyczności E';
            app.NachyleniepNmLabel_13 = uilabel(app.ModuelastycznociETab_3);
            app.NachyleniepNmLabel_13.Text = {'Wartość średnia modułu elastyczności E [Pa]: -'; 'Mediana modułu elastyczności E [Pa]: -'; ''; 'Test Shapiro-Wilka normalności rozkładu:'; 'p: -'; 'W: -'; ''; 'Dopasowany rozkład: -'; ''; 'Korelacje (R Pearsona):'; 'Elastyczność-adhezja: -'; 'Elastyczność-praca: -'};
            app.Switch_9 = uiswitch(app.ModuelastycznociETab_3, 'slider');
            app.Switch_9.Items = {'Średnia', 'Mediana'};
            app.Switch_9.Value = 'Średnia';
            app.TabGroup3_9 = uitabgroup(app.ModuelastycznociETab_3);
            app.HistogramTab_9 = uitab(app.TabGroup3_9);
            app.HistogramTab_9.Title = 'Histogram';
            app.UIAxes2_31 = uiaxes(app.HistogramTab_9);
            title(app.UIAxes2_31, 'Histogram modułu elastyczności E - wszystkie pomiary')
            xlabel(app.UIAxes2_31, 'Liczba zliczeń (szer. binu: - Pa)')
            ylabel(app.UIAxes2_31, 'Moduł elastyczności E [Pa]')
            app.RozrzutelastycznoadhezjaTab_3 = uitab(app.TabGroup3_9);
            app.RozrzutelastycznoadhezjaTab_3.Title = 'Rozrzut elastyczność-adhezja';
            app.UIAxes2_32 = uiaxes(app.RozrzutelastycznoadhezjaTab_3);
            title(app.UIAxes2_32, 'Rozrzut elastyczność-adhezja - wszystkie pomiary')
            xlabel(app.UIAxes2_32, 'Moduł elastyczności E [Pa]')
            ylabel(app.UIAxes2_32, 'Maksymalne oddziaływanie adhezyjne [pN]')
            app.RozrzutelastycznopracaTab_3 = uitab(app.TabGroup3_9);
            app.RozrzutelastycznopracaTab_3.Title = 'Rozrzut elastyczność-praca';
            app.UIAxes2_33 = uiaxes(app.RozrzutelastycznopracaTab_3);
            title(app.UIAxes2_33, 'Rozrzut elastyczność-praca - wszystkie pomiary')
            xlabel(app.UIAxes2_33, 'Praca zerwania wiązań [zJ]')
            ylabel(app.UIAxes2_33, 'Moduł elastyczności E [Pa]')
            app.ROITab_2 = uitab(app.TabGroup_2);
            app.ROITab_2.Title = 'ROI';
            app.TabGroup_4 = uitabgroup(app.ROITab_2);
            app.MaksymalnaadhezjaTab_2 = uitab(app.TabGroup_4);
            app.MaksymalnaadhezjaTab_2.Title = 'Maksymalna adhezja ';
            app.NachyleniepNmLabel_8 = uilabel(app.MaksymalnaadhezjaTab_2);
            app.NachyleniepNmLabel_8.Text = {'Wartość średnia maksymalnej adhezji [pN]: -'; 'Mediana maksymalnej adhezji [pN]: -'; ''; 'Test Shapiro-Wilka normalności rozkładu:'; 'p: -'; 'W: -'; ''; 'Dopasowany rozkład: -'; ''; 'Korelacje (R Pearsona):'; 'Adhezja-elastyczność: -'; 'Adhezja-praca: -'};
            app.Switch_4 = uiswitch(app.MaksymalnaadhezjaTab_2, 'slider');
            app.Switch_4.Items = {'Średnia', 'Mediana'};
            app.Switch_4.Value = 'Średnia';
            app.TabGroup3_4 = uitabgroup(app.MaksymalnaadhezjaTab_2);
            app.HistogramTab_4 = uitab(app.TabGroup3_4);
            app.HistogramTab_4.Title = 'Histogram';
            app.UIAxes2_16 = uiaxes(app.HistogramTab_4);
            title(app.UIAxes2_16, 'Histogram maksymalnego oddziaływania adhezyjnego - ROI')
            xlabel(app.UIAxes2_16, 'Liczba zliczeń (szer. binu: - pN)')
            ylabel(app.UIAxes2_16, 'Maksymalne oddziaływanie adhezyjne [pN]')
            app.RozrzutadhezjaelastycznoTab_2 = uitab(app.TabGroup3_4);
            app.RozrzutadhezjaelastycznoTab_2.Title = 'Rozrzut adhezja-elastyczność';
            app.UIAxes2_17 = uiaxes(app.RozrzutadhezjaelastycznoTab_2);
            title(app.UIAxes2_17, 'Rozrzut adhezja-elastyczność - ROI')
            xlabel(app.UIAxes2_17, 'Maksymalne oddziaływanie adhezyjne [pN]')
            ylabel(app.UIAxes2_17, 'Moduł elastyczności E [Pa]')
            app.RozrzutadhezjapracaTab_2 = uitab(app.TabGroup3_4);
            app.RozrzutadhezjapracaTab_2.Title = 'Rozrzut adhezja-praca';
            app.UIAxes2_18 = uiaxes(app.RozrzutadhezjapracaTab_2);
            title(app.UIAxes2_18, 'Rozrzut adhezja-praca - ROI')
            xlabel(app.UIAxes2_18, 'Maksymalne oddziaływanie adhezyjne [pN]')
            ylabel(app.UIAxes2_18, 'Praca zerwania wiązań [zJ]')
            app.PracazerwaniawizaTab_2 = uitab(app.TabGroup_4);
            app.PracazerwaniawizaTab_2.Title = 'Praca zerwania wiązań';
            app.NachyleniepNmLabel_9 = uilabel(app.PracazerwaniawizaTab_2);
            app.NachyleniepNmLabel_9.Text = {'Wartość średnia pracy zerwania wiązań [zJ]: -'; 'Mediana pracy zerwania wiązań [zJ]: -'; ''; 'Test Shapiro-Wilka normalności rozkładu:'; 'p: -'; 'W: -'; ''; 'Dopasowany rozkład: -'; ''; 'Korelacje (R Pearsona):'; 'Praca-elastyczność: -'; 'Praca-adhezja: -'};
            app.Switch_5 = uiswitch(app.PracazerwaniawizaTab_2, 'slider');
            app.Switch_5.Items = {'Średnia', 'Mediana'};
            app.Switch_5.Value = 'Średnia';
            app.TabGroup3_5 = uitabgroup(app.PracazerwaniawizaTab_2);
            app.HistogramTab_5 = uitab(app.TabGroup3_5);
            app.HistogramTab_5.Title = 'Histogram';
            app.UIAxes2_19 = uiaxes(app.HistogramTab_5);
            title(app.UIAxes2_19, 'Histogram pracy zerwania wiązań - ROI')
            xlabel(app.UIAxes2_19, 'Liczba zliczeń (szer. binu: - zJ)')
            ylabel(app.UIAxes2_19, 'Praca zerwania wiązań [zJ]')
            app.RozrzutpracaelastycznoTab_2 = uitab(app.TabGroup3_5);
            app.RozrzutpracaelastycznoTab_2.Title = 'Rozrzut praca-elastyczność';
            app.UIAxes2_20 = uiaxes(app.RozrzutpracaelastycznoTab_2);
            title(app.UIAxes2_20, 'Rozrzut praca-elastyczność - ROI')
            xlabel(app.UIAxes2_20, 'Praca zerwania wiązań [zJ]')
            ylabel(app.UIAxes2_20, 'Moduł elastyczności E [Pa]')
            app.RozrzutpracaadhezjaTab_5 = uitab(app.TabGroup3_5);
            app.RozrzutpracaadhezjaTab_5.Title = 'Rozrzut praca-adhezja';
            app.UIAxes2_21 = uiaxes(app.RozrzutpracaadhezjaTab_5);
            title(app.UIAxes2_21, 'Rozrzut praca-adhezja - ROI')
            xlabel(app.UIAxes2_21, 'Praca zerwania wiązań [zJ]')
            ylabel(app.UIAxes2_21, 'Maksymalne oddziaływanie adhezyjne [pN]')
            app.ModuelastycznociETab_2 = uitab(app.TabGroup_4);
            app.ModuelastycznociETab_2.Title = 'Moduł elastyczności E';
            app.NachyleniepNmLabel_10 = uilabel(app.ModuelastycznociETab_2);
            app.NachyleniepNmLabel_10.Text = {'Wartość średnia modułu elastyczności E [Pa]: -'; 'Mediana modułu elastyczności E [Pa]: -'; ''; 'Test Shapiro-Wilka normalności rozkładu:'; 'p: -'; 'W: -'; ''; 'Dopasowany rozkład: -'; ''; 'Korelacje (R Pearsona):'; 'Elastyczność-adhezja: -'; 'Elastyczność-praca: -'};
            app.Switch_6 = uiswitch(app.ModuelastycznociETab_2, 'slider');
            app.Switch_6.Items = {'Średnia', 'Mediana'};
            app.Switch_6.Value = 'Średnia';
            app.TabGroup3_6 = uitabgroup(app.ModuelastycznociETab_2);
            app.HistogramTab_6 = uitab(app.TabGroup3_6);
            app.HistogramTab_6.Title = 'Histogram';
            app.UIAxes2_22 = uiaxes(app.HistogramTab_6);
            title(app.UIAxes2_22, 'Histogram modułu elastyczności E - ROI')
            xlabel(app.UIAxes2_22, 'Liczba zliczeń (szer. binu: - Pa)')
            ylabel(app.UIAxes2_22, 'Moduł elastyczności E [Pa]')
            app.RozrzutelastycznoadhezjaTab_2 = uitab(app.TabGroup3_6);
            app.RozrzutelastycznoadhezjaTab_2.Title = 'Rozrzut elastyczność-adhezja';
            app.UIAxes2_23 = uiaxes(app.RozrzutelastycznoadhezjaTab_2);
            title(app.UIAxes2_23, 'Rozrzut elastyczność-adhezja - ROI')
            xlabel(app.UIAxes2_23, 'Moduł elastyczności E [Pa]')
            ylabel(app.UIAxes2_23, 'Maksymalne oddziaływanie adhezyjne [pN]')
            app.RozrzutelastycznopracaTab_2 = uitab(app.TabGroup3_6);
            app.RozrzutelastycznopracaTab_2.Title = 'Rozrzut elastyczność-praca';
            app.UIAxes2_24 = uiaxes(app.RozrzutelastycznopracaTab_2);
            title(app.UIAxes2_24, 'Rozrzut elastyczność-praca - ROI')
            xlabel(app.UIAxes2_24, 'Praca zerwania wiązań [zJ]')
            ylabel(app.UIAxes2_24, 'Moduł elastyczności E [Pa]')
            app.Panel = uipanel(app.UIFigure);
            app.DaneMapaLabel = uilabel(app.Panel);
            app.DaneMapaLabel.Text = {'Nazwa pliku: -'; 'Data akwizycji: -'; 'Godzina akwizycji: -'; ''; 'Środowisko pomiaru: -'; 'Mod: -'; 'Typ próbnika: -'; ''; 'Szybkość przykładania siły [µm/s]: -'; 'Długość Z [µm]: - '; ''; 'Wymiary mapy wys. x szer. [px]: -'; 'Wymiary mapy wys. x szer. [µm]: -'; ''; 'Czas wydłużania [s]: -'; 'Przerwa (wysłużanie) [s]: -'; 'Czas retrakcji [s]: -'; 'Przerwa (retrakcja) [s]: -'};
            app.OKNOMAPYLabel = uilabel(app.Panel);
            app.OKNOMAPYLabel.Text = 'OKNO MAPY';
            app.TabGroup = uitabgroup(app.Panel);
            app.MapaTab = uitab(app.TabGroup);
            app.MapaTab.Title = 'Mapa';
            app.UITable = uitable(app.MapaTab);
            app.UITable.ColumnName = {'Nr'; 'Pozycja X'; 'Pozycja Y'; 'Min Y [pN]'; 'Max Y [pN]'; 'Min X [μm]'; 'Max X [μm]'; 'Praca [zJ]'; 'E [Pa]'; 'Nachylenie [pN/μm]'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @SelectCell_Callback, true);
            app.ROITab = uitab(app.TabGroup);
            app.ROITab.Title = 'ROI';
            app.UITable_2 = uitable(app.ROITab);
            app.UITable_2.ColumnName = {'Nr'; 'Pozycja X'; 'Pozycja Y'; 'Min Y [pN]'; 'Max Y [pN]'; 'Min X [μm]'; 'Max X [μm]'; 'Praca [zJ]'; 'E [Pa]'; 'Nachylenie [pN/μm]'};
            app.UITable_2.RowName = {};
            app.POMIARYPARAMETRYLabel = uilabel(app.Panel);
            app.POMIARYPARAMETRYLabel.Text = 'POMIARY - PARAMETRY';
            app.TabGroup7 = uitabgroup(app.Panel);
            app.MapaoryginalnaTab = uitab(app.TabGroup7);
            app.MapaoryginalnaTab.Title = 'Mapa oryginalna';
            app.UIAxes_2 = uiaxes(app.MapaoryginalnaTab);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.XTickLabel = '';
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.YTickLabel = '';
            app.MAXAdhezjaTab = uitab(app.TabGroup7);
            app.MAXAdhezjaTab.Title = 'MAX Adhezja';
            app.UIAxes = uiaxes(app.MAXAdhezjaTab);
            app.UIAxes.XTick = [];
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTick = [];
            app.UIAxes.YTickLabel = '';
            app.ElastycznoETab = uitab(app.TabGroup7);
            app.ElastycznoETab.Title = 'Elastyczność E';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Digi4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end